classdef View < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CreatenewtoolboxLabel          matlab.ui.control.Label
        ToolboxnameEditFieldLabel      matlab.ui.control.Label
        ToolboxnameEditField           matlab.ui.control.EditField
        ShortDecriptionEditFieldLabel  matlab.ui.control.Label
        ShortDecriptionEditField       matlab.ui.control.EditField
        SubfolderEditFieldLabel        matlab.ui.control.Label
        SubfolderEditField             matlab.ui.control.EditField
        PathplacementDropDownLabel     matlab.ui.control.Label
        PathplacementDropDown          matlab.ui.control.DropDown
        CreateButton                   matlab.ui.control.Button
        FilterToolboxesEditField       matlab.ui.control.EditField
        ToolboxesListBox               matlab.ui.control.ListBox
        DependenciesLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        model      % MVC model
        controller % MVC controller
    end
    
    methods (Access = public)
        function init(app, controller, model)
            app.controller = controller;
            app.model = model;
        end
        
        function setNewToolboxName(app, newToolboxName)
            app.ToolboxnameEditField.Value = newToolboxName;
        end
        
        function setToolboxNames(app, toolboxNames)
            app.ToolboxesListBox.Items = toolboxNames;
        end

        function s = getSubfolder(app)
            s = app.SubfolderEditField.Value;
        end
        
        function s = getShortDescription(app)            
            s = app.ShortDecriptionEditField.Value;
        end
        
        function deps = getDependencies(app)
            deps = app.ToolboxesListBox.Value;
        end
        
        function p = getPathPlacement(app)
            p = app.PathplacementDropDown.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.controller.createToolbox;
        end

        % Value changing function: FilterToolboxesEditField
        function FilterToolboxesEditFieldValueChanging(app, event)
            filteredToolboxNames = app.controller.filterToolboxes(event.Value);
            app.ToolboxesListBox.Items = filteredToolboxNames;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreatenewtoolboxLabel = uilabel(app.UIFigure);
            app.CreatenewtoolboxLabel.Text = 'Create new toolbox';
            app.ToolboxnameEditFieldLabel = uilabel(app.UIFigure);
            app.ToolboxnameEditFieldLabel.Text = 'Toolbox name';
            app.ToolboxnameEditField = uieditfield(app.UIFigure, 'text');
            app.ToolboxnameEditField.Editable = 'off';
            app.ToolboxnameEditField.Enable = 'off';
            app.ShortDecriptionEditFieldLabel = uilabel(app.UIFigure);
            app.ShortDecriptionEditFieldLabel.Text = 'Short Decription';
            app.ShortDecriptionEditField = uieditfield(app.UIFigure, 'text');
            app.SubfolderEditFieldLabel = uilabel(app.UIFigure);
            app.SubfolderEditFieldLabel.Text = 'Subfolder';
            app.SubfolderEditField = uieditfield(app.UIFigure, 'text');
            app.PathplacementDropDownLabel = uilabel(app.UIFigure);
            app.PathplacementDropDownLabel.Text = 'Path placement';
            app.PathplacementDropDown = uidropdown(app.UIFigure);
            app.PathplacementDropDown.Items = {'none', 'prependrootonly', 'prepend', 'appendrootonly', 'append', 'mltbx'};
            app.PathplacementDropDown.Value = 'append';
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.FilterToolboxesEditField = uieditfield(app.UIFigure, 'text');
            app.FilterToolboxesEditField.ValueChangingFcn = createCallbackFcn(app, @FilterToolboxesEditFieldValueChanging, true);
            app.ToolboxesListBox = uilistbox(app.UIFigure);
            app.ToolboxesListBox.Multiselect = 'on';
            app.ToolboxesListBox.Value = {};
            app.DependenciesLabel = uilabel(app.UIFigure);
            app.DependenciesLabel.Text = 'Dependencies';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = View

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end